classdef Step8_CNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModelLabel                 matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        Button_9                   matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        AccuracyofMachineLearningButtonGroup  matlab.ui.container.ButtonGroup
        Button_5                   matlab.ui.control.RadioButton
        Button_4                   matlab.ui.control.RadioButton
        Button_3                   matlab.ui.control.RadioButton
        FinalButton_2              matlab.ui.control.RadioButton
        ImagefolderselectionLabel  matlab.ui.control.Label
        ImageButton                matlab.ui.control.Button
        UIAxes3_2                  matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % UI上で管理する変数を定義
        imds % Description
        selectedButton
        classifier
        netresult
        Text
        accuracy
        m
        data
        idx
        YPred
        score
        r
        numnet
        folder_name
        thumsize
    end
    
    methods (Access = private) % このアプリ内で利用する関数を定義
        % 画像データを読み出した時に実行する動作
        function readimagesaction(app)
            app.imds = imageDatastore(app.folder_name, 'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');
            if numel(app.imds.Files) > 50
                app.imds = splitEachLabel(app.imds,5); % 画像の枚数が50枚以上の場合は50枚を選択
            end
            [folderpath,~,~] = fileparts(app.imds.Files{1}); % Pathだけを取り出し
            k = strfind(folderpath,'\'); % '\'マークを検索
            folder_disp = folderpath(k(end-2):k(end)); % 相対パスでのフォルダの表示
            app.ImagefolderselectionLabel.Text = [folder_disp]; % 表示
            app.accuracy = app.netresult.accuracy; % 精度の点を読み込み
            app.m = app.netresult.m; % Iterationを保存
            % 学習曲線を表示      
            plot(app.UIAxes,[1:30],app.accuracy); %
            % 保存したタイミングの精度について赤い破線を引く
            line(app.UIAxes,[0 app.m(4)],[app.accuracy(app.m(app.numnet)) app.accuracy(app.m(app.numnet))],'Color','red','LineStyle','--')
            mypredict(app, app.netresult); % 全体の推論をする関数の呼び出し
            individualresult(app); % 個別の推論&表示をする関数の呼び出し
        end
        
        
        % データ全体に対して推論
        function  mypredict(app, netresult)
          
            reset(app.imds); % データストアの実行が完了したあと読み込めないことを防ぐためにリセットをかける
            [app.YPred,app.score] = classify(app.classifier,app.imds);% 予測
            for ii = 1:numel(app.imds.Labels)
                Itest = read(app.imds); % 画像の読み込み
                YPred1 = classify(app.classifier,Itest); % 画像ごとに予測
                Itest3 = cat(3,Itest,Itest,Itest); % 画像をカラーに変換
                if ~(YPred1 == app.imds.Labels(ii))
                    Color = 'red'; % 予測結果が間違っていたら赤
                else
                    Color = 'green'; % 予測が正しければ緑
                end
                Itest3 = insertShape(Itest3,'Rectangle',[1 1 28 28],...
                    'Color', Color,'LineWidth',3); % 画像の周囲に指定した枠をつける
                app.data{ii} = Itest3; % 枠が付いたあとの画像をdataに格納
            end
            numcorrect = sum(app.YPred == app.imds.Labels);
            montage(app.data,'Size',[10 5],'Parent', app.UIAxes2,'ThumbnailSize',[app.thumsize app.thumsize]);
            app.UIAxes2.Title.String = ['Correct Answer (Green):' num2str(numcorrect) ' / 50' newline];
            % 個々の表示解析
            % ax2size = app.UIAxes2.Position;
            app.r = rectangle('Position',[0,0,app.thumsize,app.thumsize],'EdgeColor','y','LineWidth',3,'Parent', app.UIAxes2); % 黄色枠を初期値で与える
            
        end
        % 選択画像の表示とスコア表示
        function individualresult(app)
            [img,finfo] = readimage(app.imds,app.idx); % 指定した画像を取り出す
            [~,fname,ext] = fileparts(finfo.Filename); % ファイル名を取り出す
            app.UIAxes3.Title.String = [fname ext]; % ファイル名をタイトルに表示
            app.UIAxes3.Title.Interpreter = 'none'; % 
            imshow(img, 'Parent',app.UIAxes3) % 選択した画像を表示
            cats = categories(app.imds.Labels); % 正解のカテゴリ
            bar(app.UIAxes3_2, categorical(cats), app.score(app.idx,:)) % スコア棒グラフで表示
            ylim(app.UIAxes3_2, [0 1]) % スコアの縦軸の範囲を指定
            % 横が5列なのでidxに対してどこの座標にいるかを記載
            row = ceil(app.idx / 5); % 
            col = mod(app.idx,5); % 
            if col == 0 % colが0のときは
                col =5;
            end
            app.r.Position = [app.thumsize*(col-1),app.thumsize*(row-1),app.thumsize,app.thumsize]; % 指定した場所に黄色枠の移動
        end
        % 精度ごとのネットワークの選択
        function results = selectnetwork(app)
            app.selectedButton = app.AccuracyofMachineLearningButtonGroup.SelectedObject; % 選択されたラジオボタンを読み取り
            app.Text = app.selectedButton.Text; % そのテキストを読み取り
            % 条件ごとに利用するネットワークを切り替え
            if strcmp(app.Text,'Final')
                app.classifier = app.netresult.net;
                app.numnet = 4;
            elseif strcmp(app.Text,'25%') 
                app.classifier = app.netresult.net25;
                app.numnet = 1;                
            elseif strcmp(app.Text,'50%') 
                app.classifier = app.netresult.net50;
                app.numnet = 2;                
            elseif strcmp(app.Text,'75%')
                app.classifier = app.netresult.net75;
                app.numnet = 3;                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % アプリを起動したときに動作させる
            Items = ls('netresult*'); % netresultの文字列を含むファイルをリスト化
            app.idx = 1;
            app.thumsize = 33;
            app.DropDown.Items = string(Items); % ファイルをリストに読み込み
            value = app.DropDown.Value; % モデルセット読み込み
            app.netresult = load(value); % ネットワークと結果の読み込み
            app.classifier = app.netresult.net; % ネットワークを変数に格納 
            app.folder_name = 'image_other\test'; % フォルダ名image_other\testを指定
            selectnetwork(app); % 関数を実行
            readimagesaction(app); % 関数を実行
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 右向きボタンの動作
            newidx = app.idx + 1; % インデックスに１を足す
            if newidx > 50 % 50 を超えないように設定
                app.idx = newidx - 50;
            else
                app.idx = newidx;
            end
            individualresult(app); % 新しいnewidxに対してスコアを確認
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 左向きボタンの動作
            newidx = app.idx - 1; % インデックスから１を引く
            if newidx < 1 % インデックスが1より小さくならないように
                app.idx = newidx + 50;
            else
                app.idx = newidx;
            end
            individualresult(app);  % 新しいnewidxに対してスコアを確認          
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % 下向きボタンの動作
            newidx = app.idx + 5;
            if newidx > 50
                app.idx = newidx - 50;
            else
                app.idx = newidx;
            end
            individualresult(app);  % 新しいnewidxに対してスコアを確認   
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 上向きボタンの動作
            newidx = app.idx - 5;
            if newidx < 1
                app.idx = newidx + 50;
            else
                app.idx = newidx;
            end
            individualresult(app);  % 新しいnewidxに対してスコアを確認                 
        end

        % Selection changed function: AccuracyofMachineLearningButtonGroup
        function AccuracyofMachineLearningButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.AccuracyofMachineLearningButtonGroup.SelectedObject; % モデル選択のボタンが変更された場合
            selectnetwork(app); % 関数の実行
            readimagesaction(app); % 関数の実行

        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
           uidir = uigetdir;
           figure(app.UIFigure);
           if uidir == 0
           else
           app.folder_name = [uidir '\test']; % 画像データを変更するボタンを押した場合、GUIからフォルダ名を取得
           end
           readimagesaction(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value; % モデルセットを変更
            app.netresult = load(value); % ネットワークと結果の読み直し
            selectnetwork(app); % ネットワークの選択
            readimagesaction(app); % 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Machine Learning')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Accuracy [%]')
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Score')
            xlabel(app.UIAxes3_2, 'Category')
            ylabel(app.UIAxes3_2, 'Probability')
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.ImagefolderselectionLabel = uilabel(app.UIFigure);
            app.ImagefolderselectionLabel.Text = 'Image folder selection';
            app.AccuracyofMachineLearningButtonGroup = uibuttongroup(app.UIFigure);
            app.AccuracyofMachineLearningButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AccuracyofMachineLearningButtonGroupSelectionChanged, true);
            app.AccuracyofMachineLearningButtonGroup.Title = 'Accuracy of Machine Learning';
            app.FinalButton_2 = uiradiobutton(app.AccuracyofMachineLearningButtonGroup);
            app.FinalButton_2.Text = 'Final';
            app.FinalButton_2.Value = true;
            app.Button_3 = uiradiobutton(app.AccuracyofMachineLearningButtonGroup);
            app.Button_3.Text = '25%';
            app.Button_4 = uiradiobutton(app.AccuracyofMachineLearningButtonGroup);
            app.Button_4.Text = '50%';
            app.Button_5 = uiradiobutton(app.AccuracyofMachineLearningButtonGroup);
            app.Button_5.Text = '75%';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '→';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '←';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '↑';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '↓';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ModelLabel = uilabel(app.UIFigure);
            app.ModelLabel.Text = 'Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Step8_CNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end