classdef app_building < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResultLabel  matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    img
    damageCount      % 損傷候補数
    damageScore      % 損傷らしさスコア
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
[file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp','Image Files'});

if isequal(file,0)
    return;
end

filepath = fullfile(path,file);

app.img = imread(filepath);

imshow(app.img,'Parent',app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
% 建物異常候補検出・点検支援システム

if isempty(app.img)
    uialert(app.UIFigure,'先に画像を選択してください。','エラー');
    return;
end

% ROI選択
imshow(app.img,'Parent',app.UIAxes);
title(app.UIAxes,'壁面または建物部分をドラッグして選択してください');

roi = drawrectangle(app.UIAxes);
wait(roi);

rect = round(roi.Position);

if rect(3) < 30 || rect(4) < 30
    delete(roi);
    uialert(app.UIFigure,'選択範囲が小さすぎます。もう一度選択してください。','ROIエラー');
    return;
end

cropImg = imcrop(app.img,rect);
delete(roi);

% 壁面抽出（HSV）
HSV = rgb2hsv(cropImg);
S = HSV(:,:,2);
V = HSV(:,:,3);

wallMask = (S < 0.45) & (V > 0.25);
wallMask = bwareaopen(wallMask,500);
wallMask = imfill(wallMask,'holes');

wallArea = sum(wallMask(:));

if wallArea < 100
    uialert(app.UIFigure,'壁面候補を十分に抽出できませんでした。ROIを選び直してください。','壁面抽出エラー');
    return;
end

wallImg = cropImg;
mask3 = repmat(wallMask,[1 1 3]);
wallImg(~mask3) = 255;

% グレースケール化・コントラスト補正
gray = rgb2gray(wallImg);
gray = adapthisteq(gray);

% ボトムハット変換
se = strel('disk',15);
bottomHat = imbothat(gray,se);
bottomHat = imadjust(bottomHat);

% 適応的二値化
BW = imbinarize(bottomHat,...
    'adaptive',...
    'ForegroundPolarity','bright',...
    'Sensitivity',0.55);

BW(~wallMask) = false;

% ノイズ除去・形態処理
BW = bwareaopen(BW,30);
BW = imclose(BW,strel('disk',2));
BW = imfill(BW,'holes');

% ラベリング
CC = bwconncomp(BW);

stats = regionprops(CC,...
    gray,...
    'Area',...
    'BoundingBox',...
    'Centroid',...
    'Eccentricity',...
    'Extent',...
    'Solidity',...
    'MeanIntensity');

% 表示準備
imshow(cropImg,'Parent',app.UIAxes2);
hold(app.UIAxes2,'on');

boxes = [];
candidateScores = [];
rawCandidateCount = 0;
score = 0;

% 異常候補抽出
for k = 1:length(stats)

    area = stats(k).Area;
    ecc = stats(k).Eccentricity;
    solidity = stats(k).Solidity;
    extent = stats(k).Extent;
    intensity = stats(k).MeanIntensity;
    box = stats(k).BoundingBox;

    if area < 40
        continue;
    end

    if area > 3500
        continue;
    end

    if intensity > 180
        continue;
    end

    if solidity > 0.97
        continue;
    end

    if extent > 0.95
        continue;
    end

    if ecc < 0.15
        continue;
    end

    rawCandidateCount = rawCandidateCount + 1;
    boxes = [boxes; box];

    tempScore = 0;

    if area > 700
        tempScore = tempScore + 3;
    elseif area > 300
        tempScore = tempScore + 2;
    else
        tempScore = tempScore + 1;
    end

    if intensity < 100
        tempScore = tempScore + 3;
    elseif intensity < 140
        tempScore = tempScore + 2;
    else
        tempScore = tempScore + 1;
    end

    if ecc > 0.80
        tempScore = tempScore + 2;
    end

    if solidity < 0.60
        tempScore = tempScore + 2;
    end

    candidateScores = [candidateScores; tempScore];
    score = score + tempScore;

end

% 候補がない場合
if isempty(boxes)

    hold(app.UIAxes2,'off');

    priority = '低';
    comment = '現時点では大きな点検候補は確認されませんでした。';

    result = sprintf(...
    '建物点検支援結果\n\n点検エリア数：0\n点検優先度：%s\n\n推奨対応\n%s\n\n赤枠は点検を推奨する範囲です。\n最終判断は点検者が行ってください。',...
    priority,...
    comment);

    app.ResultLabel.Text = result;

    title(app.UIAxes2,...
        sprintf('点検エリア：0箇所   優先度：%s',priority),...
        'FontSize',14,...
        'FontWeight','bold');

    return;

end

% 点検エリア統合
mergeDistance = 90;

mergedBoxes = [];
mergedScores = [];

used = false(size(boxes,1),1);

for i = 1:size(boxes,1)

    if used(i)
        continue;
    end

    current = boxes(i,:);
    currentScore = candidateScores(i);

    used(i) = true;
    changed = true;

    while changed

        changed = false;

        for j = 1:size(boxes,1)

            if used(j)
                continue;
            end

            target = boxes(j,:);

            cx1 = current(1) + current(3)/2;
            cy1 = current(2) + current(4)/2;
            cx2 = target(1) + target(3)/2;
            cy2 = target(2) + target(4)/2;

            d = sqrt((cx1-cx2)^2 + (cy1-cy2)^2);

            xA = max(current(1),target(1));
            yA = max(current(2),target(2));
            xB = min(current(1)+current(3),target(1)+target(3));
            yB = min(current(2)+current(4),target(2)+target(4));

            interW = max(0,xB-xA);
            interH = max(0,yB-yA);
            interArea = interW * interH;

            area1 = current(3)*current(4);
            area2 = target(3)*target(4);
            unionArea = area1 + area2 - interArea;

            if unionArea == 0
                iou = 0;
            else
                iou = interArea / unionArea;
            end

            if d < mergeDistance || iou > 0.02

                x1 = min(current(1),target(1));
                y1 = min(current(2),target(2));
                x2 = max(current(1)+current(3),target(1)+target(3));
                y2 = max(current(2)+current(4),target(2)+target(4));

                current = [x1 y1 x2-x1 y2-y1];
                currentScore = currentScore + candidateScores(j);

                used(j) = true;
                changed = true;

            end

        end

    end

    mergedBoxes = [mergedBoxes; current];
    mergedScores = [mergedScores; currentScore];

end

% 小さすぎる点検エリアを除外
finalBoxes = [];
finalScores = [];

for i = 1:size(mergedBoxes,1)

    box = mergedBoxes(i,:);
    areaBox = box(3)*box(4);

    if areaBox < 300
        continue;
    end

    finalBoxes = [finalBoxes; box];
    finalScores = [finalScores; mergedScores(i)];

end

mergedBoxes = finalBoxes;
mergedScores = finalScores;

% 点検エリア表示
margin = 30;

for i = 1:size(mergedBoxes,1)

    box = mergedBoxes(i,:);

    x = max(box(1)-margin,1);
    y = max(box(2)-margin,1);
    w = box(3) + margin*2;
    h = box(4) + margin*2;

    imgH = size(cropImg,1);
    imgW = size(cropImg,2);

    if x + w > imgW
        w = imgW - x;
    end

    if y + h > imgH
        h = imgH - y;
    end

    drawBox = [x y w h];

    rectangle(app.UIAxes2,...
        'Position',drawBox,...
        'EdgeColor','red',...
        'LineWidth',3);

    text(app.UIAxes2,...
        x,...
        max(y-10,1),...
        sprintf('点検%d',i),...
        'Color','yellow',...
        'FontSize',14,...
        'FontWeight','bold');

end

hold(app.UIAxes2,'off');

% 点検エリア面積・異常率計算
inspectionArea = 0;

for i = 1:size(mergedBoxes,1)
    box = mergedBoxes(i,:);
    inspectionArea = inspectionArea + box(3)*box(4);
end

if wallArea == 0
    inspectionRate = 0;
else
    inspectionRate = inspectionArea / wallArea * 100;
end

% 点検優先度判定
priorityScore = score;

priorityScore = priorityScore + size(mergedBoxes,1);

if inspectionRate > 10
    priorityScore = priorityScore + 10;
elseif inspectionRate > 5
    priorityScore = priorityScore + 7;
elseif inspectionRate > 2
    priorityScore = priorityScore + 4;
elseif inspectionRate > 1
    priorityScore = priorityScore + 2;
end

if priorityScore < 15

    priority = '低';
    comment = '経過観察を推奨します。';

elseif priorityScore < 30

    priority = '中';
    comment = '定期点検を推奨します。';

elseif priorityScore < 50

    priority = '高';
    comment = '優先的な現地点検を推奨します。';

else

    priority = '最優先';
    comment = '至急点検してください。';

end

% 結果表示
result = sprintf(...
'建物点検支援結果\n\n点検エリア数：%d\n点検優先度：%s\n\n推奨対応\n%s\n\n赤枠は点検を推奨する範囲です。\n最終判断は点検者が行ってください。',...
size(mergedBoxes,1),...
priority,...
comment);

app.ResultLabel.Text = result;

title(app.UIAxes2,...
    sprintf('点検エリア：%d箇所   優先度：%s',size(mergedBoxes,1),priority),...
    'FontSize',14,...
    'FontWeight','bold');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '元画像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '処理画像')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '建物損傷可視化システム';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '画像選択';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '損傷解析';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'ResultLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_building

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end