classdef app_sidewalk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
[file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp','Image Files'});

if isequal(file,0)
    return;
end

filepath = fullfile(path,file);

app.img = imread(filepath);

imshow(app.img,'Parent',app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
% 画像が選択されているか確認
if isempty(app.img)
    uialert(app.UIFigure,'先に画像を選択してください','エラー');
    return;
end

img = app.img;

% ROI選択
imshow(img,'Parent',app.UIAxes);
title(app.UIAxes,'歩道部分を選択してください');

roi = drawrectangle(app.UIAxes);
wait(roi);

rect = round(roi.Position);
cropImg = imcrop(img,rect);

delete(roi);

% グレースケール化
gray = rgb2gray(cropImg);

% コントラスト強調
gray = adapthisteq(gray);

% エッジ検出
BW = edge(gray,'canny');

% ノイズ除去
BW = bwareaopen(BW,30);

% 線をつなげる
BW = imclose(BW,strel('line',5,0));
BW = imclose(BW,strel('line',5,90));

% ラベリング
CC = bwconncomp(BW);

stats = regionprops(CC,...
    'Area',...
    'MajorAxisLength',...
    'MinorAxisLength',...
    'BoundingBox');

% 表示
imshow(cropImg,'Parent',app.UIAxes2);
hold(app.UIAxes2,'on');

boxes = [];
score = 0;

% 危険候補抽出
for k = 1:length(stats)

    area = stats(k).Area;
    major = stats(k).MajorAxisLength;
    minor = stats(k).MinorAxisLength;
    box = stats(k).BoundingBox;

    if minor == 0
        continue;
    end

    ratio = major / minor;

    % 小さすぎるノイズ除外
    if area < 50
        continue;
    end

    % 大きすぎる物体除外
    if area > 2000
        continue;
    end

    % 細長い線状の候補を抽出
    if ratio < 3
        continue;
    end

    % 短すぎる線を除外
    if major < 40
        continue;
    end

    boxes = [boxes; box];

    % スコア計算
    temp = 1;

    if major > 80
        temp = temp + 2;
    end

    if ratio > 6
        temp = temp + 2;
    end

    if area > 300
        temp = temp + 1;
    end

    score = score + temp;

end

% 近い候補を統合
mergeDistance = 80;

mergedBoxes = [];
used = false(size(boxes,1),1);

for i = 1:size(boxes,1)

    if used(i)
        continue;
    end

    current = boxes(i,:);
    used(i) = true;
    changed = true;

    while changed
        changed = false;

        for j = 1:size(boxes,1)

            if used(j)
                continue;
            end

            target = boxes(j,:);

            cx1 = current(1) + current(3)/2;
            cy1 = current(2) + current(4)/2;

            cx2 = target(1) + target(3)/2;
            cy2 = target(2) + target(4)/2;

            d = sqrt((cx1-cx2)^2 + (cy1-cy2)^2);

            if d < mergeDistance

                x1 = min(current(1),target(1));
                y1 = min(current(2),target(2));

                x2 = max(current(1)+current(3),target(1)+target(3));
                y2 = max(current(2)+current(4),target(2)+target(4));

                current = [x1 y1 x2-x1 y2-y1];

                used(j) = true;
                changed = true;

            end
        end
    end

    mergedBoxes = [mergedBoxes; current];

end

% 注意エリア表示
margin = 25;

for i = 1:size(mergedBoxes,1)

    box = mergedBoxes(i,:);

    x = max(box(1)-margin,1);
    y = max(box(2)-margin,1);
    w = box(3) + margin*2;
    h = box(4) + margin*2;

    rectangle(app.UIAxes2,...
        'Position',[x y w h],...
        'EdgeColor','red',...
        'LineWidth',3);

    text(app.UIAxes2,...
        x,...
        max(y-8,1),...
        sprintf('注意%d',i),...
        'Color','yellow',...
        'FontSize',14,...
        'FontWeight','bold');

end

hold(app.UIAxes2,'off');

% 危険度判定
areaCount = size(mergedBoxes,1);

priorityScore = score + areaCount * 2;

if priorityScore < 10

    level = '危険度：低';
    comment = '大きな危険候補は少ないため、経過観察を推奨します。';

elseif priorityScore < 25

    level = '危険度：中';
    comment = '歩行時に注意が必要な箇所があります。';

else

    level = '危険度：高';
    comment = '優先的な現地点検を推奨します。';

end

% 結果表示
resultText = sprintf(...
'歩道点検支援結果\n\n注意エリア数：%d\n%s\n\n推奨対応\n%s\n\n※赤枠は歩行時に注意が必要な候補範囲です。\n最終判断は点検者が行ってください。',...
areaCount,...
level,...
comment);

app.Label.Text = resultText;

title(app.UIAxes2,...
    sprintf('注意エリア：%d箇所   %s',areaCount,level),...
    'FontSize',13,...
    'FontWeight','bold');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '元画像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '処理画像')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '歩道危険候補箇所可視化';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '画像選択';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '危険個所解析';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_sidewalk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end